classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RunSimulationButton      matlab.ui.control.Button
        ConductorsinanElectricFieldLabel  matlab.ui.control.Label
        SurfaceFunctionDropDown  matlab.ui.control.DropDown
        EFunctionDropDown        matlab.ui.control.DropDown
        ElectricFunctionLabel    matlab.ui.control.Label
        SurfaceFunctionLabel     matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    %% Project paths

    appFolder = fileparts(mfilename('fullpath'));

    srcFolder     = fullfile(appFolder, 'src');
    surfaceFolder = fullfile(appFolder, 'Functions', 'surface');
    eFieldFolder  = fullfile(appFolder, 'Functions', 'E_function');


    %% Add paths

    addpath(srcFolder);
    addpath(surfaceFolder);
    addpath(eFieldFolder);


    %% Find Electric Field functions

    EFiles = dir(fullfile(eFieldFolder, '*.m'));

    EFunctionNames = erase({EFiles.name}, '.m');


    %% Find Surface functions

    SurfaceFiles = dir(fullfile(surfaceFolder, '*.m'));

    SurfaceFunctionNames = erase({SurfaceFiles.name}, '.m');


    %% Fill DropDowns

    app.EFunctionDropDown.Items = EFunctionNames;

    app.SurfaceFunctionDropDown.Items = SurfaceFunctionNames;

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

    %% Get selected function names

    EFunctionName = app.EFunctionDropDown.Value;

    SurfaceFunctionName = app.SurfaceFunctionDropDown.Value;


    %% Convert names to function handles

    EFunctionFile = str2func(EFunctionName);

    SurfaceFunctionFile = str2func(SurfaceFunctionName);


    %% Execute selected files

    EFunction = EFunctionFile();

    SurfaceFunction = SurfaceFunctionFile();


    %% Run simulation

    Core(EFunction, SurfaceFunction);


        end

        % Value changed function: SurfaceFunctionDropDown
        function SurfaceFunctionDropDownValueChanged(app, event)
            value = app.SurfaceFunctionDropDown.Value;
            
        end

        % Value changed function: EFunctionDropDown
        function EFunctionDropDownValueChanged(app, event)
            value = app.EFunctionDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ConductorsinanElectricFieldLabel = uilabel(app.UIFigure);
            app.ConductorsinanElectricFieldLabel.Text = 'Conductors-in-an-Electric-Field';
            app.SurfaceFunctionDropDown = uidropdown(app.UIFigure);
            app.SurfaceFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceFunctionDropDownValueChanged, true);
            app.EFunctionDropDown = uidropdown(app.UIFigure);
            app.EFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @EFunctionDropDownValueChanged, true);
            app.ElectricFunctionLabel = uilabel(app.UIFigure);
            app.ElectricFunctionLabel.Text = 'Electric Function';
            app.SurfaceFunctionLabel = uilabel(app.UIFigure);
            app.SurfaceFunctionLabel.Text = 'Surface Function';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {' name : Conductor in an Electric Field '; ' date : autumn 1404 '; ' Contact me if you find any problem'; ' gmail : Pa.norouzieh@gmail.com'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end